classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ShowDetectsCheckBox  matlab.ui.control.CheckBox
        DropDownLabel        matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stop = false;
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
  
        
        app.stop = false;
    s = serial('COM7'); % change the COM Port number as needed

    s.InputBufferSize = 50; % read only one byte every time
    s.Terminator = 'CR/LF'
    s.BaudRate = 115200;
try
    fopen(s);
catch err
    fclose(instrfind);
    disp('x');
    return;
   % error('Make sure you select the correct COM Port where the Arduino is connected.');
end


datasets=4;
dctr=0;


data = [datasets,1];
detect = false;
Fs=125;

Ts = 1/Fs; % Sampling time (s)
i = 0;
data = 0;
t = 0;
tic % Start timer


buds={};

while (1)
    i = i + 1;
    %% Read buffer data
      
           lines = fscanf(s);
           
           p =  sscanf(lines,'%f %f %lu %f');
        
        
           for j = 1:datasets
                if length(p) ==datasets
                    data(j,i) = p(j);
                elseif i>1
                   data(j,i) = data(j,i-1);
                else
                    data(j,i)=0;
                end
           end
    
    %% Read time stamp
    % If reading faster than sampling rate, force sampling time.
    % If reading slower than sampling rate, nothing can be done. Consider
    % decreasing the set sampling time Ts
    t(i) = toc;
    if i > 1
        T = toc - t(i-1);
        while T < Ts
            T = toc - t(i-1);
        end
    end
    t(i) = toc;
    %% Plot live data
    if i > 1
        line(app.UIAxes,[t(i-1) t(i)],[data(1,i-1) data(1,i)],'Color','red')
        line(app.UIAxes,[t(i-1) t(i)],[data(2,i-1) data(2,i)],'Color','blue')
        
        
        line(app.UIAxes2,[t(i-1) t(i)],[data(4,i-1) data(4,i)],'Color','green')

       
        if (data(3,i-1)<data(3,i))
            
            line(app.UIAxes,[t(i) t(i)],[data(2,i) data(2,i)],'Color','magenta','Marker','*')
        end
        
        drawnow
        %axis auto
    end
    
    if (app.stop)
        break;
    end
    
end
    fclose(s);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ShowDetectsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowDetectsCheckBox.Text = 'Show Detects';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'RR')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end